classdef APYV9WebApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MainTabGroup                    matlab.ui.container.TabGroup
        ScenarioTab                     matlab.ui.container.Tab
        LoadScenarioButton              matlab.ui.control.Button
        SaveScenarioButton              matlab.ui.control.Button
        UseDefaultsCheckBox             matlab.ui.control.CheckBox
        ScenarioLabelEditField          matlab.ui.control.EditField
        ScenarioLabel                   matlab.ui.control.Label
        InputsTab                       matlab.ui.container.Tab
        RegimeEfficacyforFullCourseEditField  matlab.ui.control.NumericEditField
        RegimeEfficacyforFullCourseEditFieldLabel  matlab.ui.control.Label
        ProportionceaseduetoADREditField  matlab.ui.control.NumericEditField
        ProportionceaseduetoADREditFieldLabel  matlab.ui.control.Label
        ProportionCompleteRegimenEditField  matlab.ui.control.NumericEditField
        ProportionCompleteRegimenEditFieldLabel  matlab.ui.control.Label
        ProportionStartTPTEditField     matlab.ui.control.NumericEditField
        ProportionStartTPTEditFieldLabel  matlab.ui.control.Label
        ScreenCoverageEditField         matlab.ui.control.NumericEditField
        ScreenCoverageEditFieldLabel    matlab.ui.control.Label
        ScreeningStrategyDropDown       matlab.ui.control.DropDown
        ScreeningStrategyDropDownLabel  matlab.ui.control.Label
        RegimenDropDown                 matlab.ui.control.DropDown
        RegimenDropDownLabel            matlab.ui.control.Label
        TestTypeDropDown                matlab.ui.control.DropDown
        TesttypeLabel                   matlab.ui.control.Label
        ActiveTBPrevalenceEditField     matlab.ui.control.NumericEditField
        ActiveTBprevalenceLabel         matlab.ui.control.Label
        LtbiPrevalenceEditField         matlab.ui.control.NumericEditField
        TBIprevalenceLabel              matlab.ui.control.Label
        AdvancedTab                     matlab.ui.container.Tab
        NrepsEditField                  matlab.ui.control.NumericEditField
        NrepsEditFieldLabel             matlab.ui.control.Label
        RunTab                          matlab.ui.container.Tab
        ValidationMessagesTextArea      matlab.ui.control.TextArea
        ValidationMessagesLabel         matlab.ui.control.Label
        ValidateButton                  matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        ResultsTab                      matlab.ui.container.Tab
        TechnicalDetailsTextArea        matlab.ui.control.TextArea
        TechnicalDetailsLabel           matlab.ui.control.Label
        HeadlineResultsTable            matlab.ui.control.Table
        DownloadsTab                    matlab.ui.container.Tab
        DownloadsTable                  matlab.ui.control.Table
        ExportResultsButton             matlab.ui.control.Button
    end


    properties (Access = private)
        AppState % Description
    end


    methods (Access = private)


        function refreshValidationDisplay(app)
            displayModel = validation_report_to_display_v9(app.AppState.LastValidationReport);

            lines = {
                displayModel.summaryText
                sprintf('Valid: %d', displayModel.isValid)
                sprintf('Warnings present: %d', displayModel.hasWarnings)
                };

            if ~isempty(displayModel.errorRows)
                for i = 1:numel(displayModel.errorRows)
                    lines{end+1, 1} = ['ERROR: ' app.localIssueText(displayModel.errorRows(i))]; %#ok<AGROW>
                end
            end

            if ~isempty(displayModel.warningRows)
                for i = 1:numel(displayModel.warningRows)
                    lines{end+1, 1} = ['WARNING: ' app.localIssueText(displayModel.warningRows(i))]; %#ok<AGROW>
                end
            end

            if ~isempty(displayModel.infoRows)
                for i = 1:numel(displayModel.infoRows)
                    lines{end+1, 1} = ['INFO: ' app.localIssueText(displayModel.infoRows(i))]; %#ok<AGROW>
                end
            end

            app.ValidationMessagesTextArea.Value = lines;
        end

        function refreshResultsDisplay(app)

            if isempty(app.AppState) || isempty(app.AppState.LastBundle)
                app.setUITableData(app.HeadlineResultsTable, [], "No results available");
                app.TechnicalDetailsTextArea.Value = {'No technical details available.'};
                app.setUITableData(app.DownloadsTable, [], "No downloads available yet");
                return;
            end

            displayModel = results_bundle_to_display_v9(app.AppState.LastBundle);

            % ---------------------------
            % Headline results
            % ---------------------------
            headlineTbl = [];
            if isstruct(displayModel) && isfield(displayModel, 'headline') ...
                    && isfield(displayModel.headline, 'available') && displayModel.headline.available

                if isfield(displayModel.headline, 'keyMetricsTable') && istable(displayModel.headline.keyMetricsTable)
                    headlineTbl = displayModel.headline.keyMetricsTable;
                elseif isfield(displayModel.headline, 'summaryTable') && istable(displayModel.headline.summaryTable)
                    headlineTbl = displayModel.headline.summaryTable;
                elseif isfield(displayModel.headline, 'keyMetricsRows') && ~isempty(displayModel.headline.keyMetricsRows)
                    try
                        headlineTbl = struct2table(displayModel.headline.keyMetricsRows, 'AsArray', true);
                    catch
                    end
                end
            end
            app.setUITableData(app.HeadlineResultsTable, headlineTbl, "No results available");

            % ---------------------------
            % Technical details
            % ---------------------------
            technicalLines = {};

            if app.AppState.ResultsAreStale
                technicalLines{end+1,1} = 'Results are stale. Re-run the scenario to refresh outputs.';
            end

            if isstruct(displayModel) && isfield(displayModel, 'technical') ...
                    && isfield(displayModel.technical, 'available') && displayModel.technical.available

                technicalLines{end+1,1} = 'Technical details available.';

                if isfield(displayModel, 'metadata') && isstruct(displayModel.metadata)
                    if isfield(displayModel.metadata, 'contractVersion')
                        technicalLines{end+1,1} = ['Contract version: ' char(string(displayModel.metadata.contractVersion))];
                    end
                end

                if isfield(displayModel.technical, 'interfaceConfig') && ~isempty(displayModel.technical.interfaceConfig)
                    technicalLines{end+1,1} = 'Interface config summary:';
                    technicalLines{end+1,1} = strtrim(evalc('disp(displayModel.technical.interfaceConfig)'));
                end
            end

            if isempty(technicalLines)
                technicalLines = {'No technical details available.'};
            end

            app.TechnicalDetailsTextArea.Value = technicalLines;


            % ---------------------------
            % Downloads
            % ---------------------------
            downloadsTbl = [];

            if isstruct(displayModel) && isfield(displayModel, 'downloads') ...
                    && isstruct(displayModel.downloads) ...
                    && isfield(displayModel.downloads, 'available') ...
                    && displayModel.downloads.available

                rows = struct([]);

                if isfield(displayModel.downloads, 'summaryCsv') && ~isempty(displayModel.downloads.summaryCsv)
                    rows(end+1).FileType = "Summary CSV"; %#ok<AGROW>
                    rows(end).Path = string(displayModel.downloads.summaryCsv);
                end

                if isfield(displayModel.downloads, 'keyMetricsCsv') && ~isempty(displayModel.downloads.keyMetricsCsv)
                    rows(end+1).FileType = "Key metrics CSV"; %#ok<AGROW>
                    rows(end).Path = string(displayModel.downloads.keyMetricsCsv);
                end

                if isfield(displayModel.downloads, 'payload') && ~isempty(displayModel.downloads.payload)
                    try
                        payloadFields = fieldnames(displayModel.downloads.payload);
                        for k = 1:numel(payloadFields)
                            val = displayModel.downloads.payload.(payloadFields{k});
                            if ischar(val) || isstring(val)
                                rows(end+1).FileType = string(payloadFields{k}); %#ok<AGROW>
                                rows(end).Path = string(val);
                            end
                        end
                    catch
                    end
                end

                if ~isempty(rows)
                    downloadsTbl = struct2table(rows);
                end
            end

            app.setUITableData(app.DownloadsTable, downloadsTbl, "No downloads available yet");
        end


        function setUITableData(app, uiTable, tbl, placeholderText)

            if nargin < 4 || isempty(placeholderText)
                placeholderText = "No data available";
            end

            if istable(tbl) && ~isempty(tbl)
                uiTable.Data = tbl;
                try
                    uiTable.ColumnName = tbl.Properties.VariableNames;
                catch
                end
            else
                uiTable.Data = table(string(placeholderText), 'VariableNames', {'Status'});
                uiTable.ColumnName = {'Status'};
            end
        end
    end
    methods (Access = private)

        function InputValueChanged(app, event)
            uiState = app_collect_ui_state_v9(app, app.AppState.Schema);
            app.AppState.CurrentUIState = uiState;

            if isfield(app.AppState.CurrentConfig, 'usesDefaults')
                app.AppState.CurrentConfig.usesDefaults = logical(app.UseDefaultsCheckBox.Value);
            end

            [app.AppState, ~] = app_input_changed_v9(app.AppState, uiState);

            refreshValidationDisplay(app);
            refreshResultsDisplay(app);
        end

        function txt = localIssueText(app, issue)
            txt = '';
            if isstruct(issue)
                if isfield(issue, 'message') && ~isempty(issue.message)
                    txt = char(string(issue.message));
                elseif isfield(issue, 'text') && ~isempty(issue.text)
                    txt = char(string(issue.text));
                else
                    txt = strtrim(evalc('disp(issue)'));
                end
            else
                txt = char(string(issue));
            end
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            drawnow;
            app.UIFigure.WindowState = 'maximized';

            [app.AppState, ~, ~] = app_startup_v9();
            app = app_apply_ui_state_v9(app, app.AppState.CurrentUIState, app.AppState.Schema);

            if isfield(app.AppState.CurrentConfig, 'usesDefaults')
                app.UseDefaultsCheckBox.Value = logical(app.AppState.CurrentConfig.usesDefaults);
            end

            refreshValidationDisplay(app);
            refreshResultsDisplay(app);

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            uiState = app_collect_ui_state_v9(app, app.AppState.Schema);
            app.AppState.CurrentUIState = uiState;
            if isfield(app.AppState.CurrentConfig, 'usesDefaults')
                app.AppState.CurrentConfig.usesDefaults = logical(app.UseDefaultsCheckBox.Value);
            end
            [app.AppState, ~, runError] = app_run_v9(app.AppState, uiState);
            refreshValidationDisplay(app);
            if ~isempty(runError)
                app.TechnicalDetailsTextArea.Value = { ...
                    'Run failed.'; ...
                    runError};
                return;
            end
            refreshResultsDisplay(app);
        end

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)

            uiState = app_collect_ui_state_v9(app, app.AppState.Schema);
            app.AppState.CurrentUIState = uiState;

            if isfield(app.AppState.CurrentConfig, 'usesDefaults')
                app.AppState.CurrentConfig.usesDefaults = logical(app.UseDefaultsCheckBox.Value);
            end

            [app.AppState, ~, validationError] = app_validate_v9(app.AppState, uiState);

            refreshValidationDisplay(app);

            if ~isempty(validationError)
                app.ValidationMessagesTextArea.Value = { ...
                    'Validation failed.'; ...
                    validationError};
            end
        end

        % Button pushed function: LoadScenarioButton
        function LoadScenarioButtonPushed(app, event)

            [fileName, filePath] = uigetfile('*.json', 'Load scenario');
            if isequal(fileName, 0) || isequal(filePath, 0)
                return;
            end

            fullName = fullfile(filePath, fileName);
            [app.AppState, ~, ~, ~] = app_load_scenario_v9(app.AppState, fullName);

            app = app_apply_ui_state_v9(app, app.AppState.CurrentUIState, app.AppState.Schema);

            if isfield(app.AppState.CurrentConfig, 'usesDefaults')
                app.UseDefaultsCheckBox.Value = logical(app.AppState.CurrentConfig.usesDefaults);
            end

            refreshValidationDisplay(app);
            refreshResultsDisplay(app);

        end

        % Button pushed function: SaveScenarioButton
        function SaveScenarioButtonPushed(app, event)

            uiState = app_collect_ui_state_v9(app, app.AppState.Schema);
            app.AppState.CurrentUIState = uiState;
            app.AppState.CurrentConfig = ui_state_to_config_v9(uiState, app.AppState.Schema, app.AppState.CurrentConfig);

            if isfield(app.AppState.CurrentConfig, 'usesDefaults')
                app.AppState.CurrentConfig.usesDefaults = logical(app.UseDefaultsCheckBox.Value);
            end

            defaultFile = 'scenario_v9.json';
            if isfield(app.AppState.CurrentConfig, 'scenarioLabel') && ~isempty(app.AppState.CurrentConfig.scenarioLabel)
                defaultFile = [regexprep(char(string(app.AppState.CurrentConfig.scenarioLabel)), '[^a-zA-Z0-9_-]', '_') '.json'];
            end

            [fileName, filePath] = uiputfile('*.json', 'Save scenario', defaultFile);
            if isequal(fileName, 0) || isequal(filePath, 0)
                return;
            end

            fullName = fullfile(filePath, fileName);
            [app.AppState, ~, errMsg] = app_save_scenario_v9(app.AppState, fullName);

            if ~isempty(errMsg)
                app.ValidationMessagesTextArea.Value = { ...
                    'Save failed.'; ...
                    errMsg};
                return;
            end

            refreshValidationDisplay(app);

        end

        % Button pushed function: ExportResultsButton
        function ExportResultsButtonPushed(app, event)

            answer = inputdlg('Export base name:', 'Export results', 1, {'tb_screening_v9'});
            if isempty(answer)
                return;
            end

            baseName = strtrim(answer{1});
            if isempty(baseName)
                baseName = 'tb_screening_v9';
            end

            [app.AppState, ~, errMsg] = app_export_v9(app.AppState, baseName);

            if ~isempty(errMsg)
                app.TechnicalDetailsTextArea.Value = { ...
                    'Export failed.'; ...
                    errMsg};
                return;
            end

            refreshResultsDisplay(app);

        end

        % Value changed function: LtbiPrevalenceEditField
        function LtbiPrevalenceEditFieldValueChanged(app, event)
            value = app.LtbiPrevalenceEditField.Value;
            
        end

        % Value changed function: ActiveTBPrevalenceEditField
        function ActiveTBPrevalenceEditFieldValueChanged(app, event)
            value = app.ActiveTBPrevalenceEditField.Value;
            
        end

        % Value changed function: TestTypeDropDown
        function TestTypeDropDownValueChanged(app, event)
            value = app.TestTypeDropDown.Value;
            
        end

        % Value changed function: RegimenDropDown
        function RegimenDropDownValueChanged(app, event)
            value = app.RegimenDropDown.Value;
            
        end

        % Value changed function: ScreeningStrategyDropDown
        function ScreeningStrategyDropDownValueChanged(app, event)
            value = app.ScreeningStrategyDropDown.Value;
            
        end

        % Value changed function: ScreenCoverageEditField
        function ScreenCoverageEditFieldValueChanged(app, event)
            value = app.ScreenCoverageEditField.Value;
            
        end

        % Value changed function: ProportionStartTPTEditField
        function ProportionStartTPTEditFieldValueChanged(app, event)
            value = app.ProportionStartTPTEditField.Value;
            
        end

        % Value changed function: ProportionCompleteRegimenEditField
        function ProportionCompleteRegimenEditFieldValueChanged(app, event)
            value = app.ProportionCompleteRegimenEditField.Value;
            
        end

        % Value changed function: ProportionceaseduetoADREditField
        function ProportionceaseduetoADREditFieldValueChanged(app, event)
            value = app.ProportionceaseduetoADREditField.Value;
            
        end

        % Value changed function: RegimeEfficacyforFullCourseEditField
        function RegimeEfficacyforFullCourseEditFieldValueChanged(app, event)
            value = app.RegimeEfficacyforFullCourseEditField.Value;
            
        end

        % Value changed function: NrepsEditField
        function NrepsEditFieldValueChanged(app, event)
            value = app.NrepsEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainTabGroup = uitabgroup(app.UIFigure);
            app.ScenarioTab = uitab(app.MainTabGroup);
            app.ScenarioTab.Title = 'Scenario';
            app.ScenarioLabel = uilabel(app.ScenarioTab);
            app.ScenarioLabel.Text = 'Scenario Label';
            app.ScenarioLabelEditField = uieditfield(app.ScenarioTab, 'text');
            app.ScenarioLabelEditField.Value = 'DEFAULT';
            app.UseDefaultsCheckBox = uicheckbox(app.ScenarioTab);
            app.UseDefaultsCheckBox.Text = 'Use Defaults';
            app.SaveScenarioButton = uibutton(app.ScenarioTab, 'push');
            app.SaveScenarioButton.ButtonPushedFcn = createCallbackFcn(app, @SaveScenarioButtonPushed, true);
            app.SaveScenarioButton.Text = 'Save Scenario';
            app.LoadScenarioButton = uibutton(app.ScenarioTab, 'push');
            app.LoadScenarioButton.ButtonPushedFcn = createCallbackFcn(app, @LoadScenarioButtonPushed, true);
            app.LoadScenarioButton.Text = 'Load Scenario';
            app.InputsTab = uitab(app.MainTabGroup);
            app.InputsTab.Title = 'Inputs';
            app.TBIprevalenceLabel = uilabel(app.InputsTab);
            app.TBIprevalenceLabel.Text = 'TBI prevalence';
            app.LtbiPrevalenceEditField = uieditfield(app.InputsTab, 'numeric');
            app.LtbiPrevalenceEditField.ValueChangedFcn = createCallbackFcn(app, @LtbiPrevalenceEditFieldValueChanged, true);
            app.ActiveTBprevalenceLabel = uilabel(app.InputsTab);
            app.ActiveTBprevalenceLabel.Text = 'Active TB prevalence';
            app.ActiveTBPrevalenceEditField = uieditfield(app.InputsTab, 'numeric');
            app.ActiveTBPrevalenceEditField.ValueChangedFcn = createCallbackFcn(app, @ActiveTBPrevalenceEditFieldValueChanged, true);
            app.TesttypeLabel = uilabel(app.InputsTab);
            app.TesttypeLabel.Text = 'Test Type';
            app.TestTypeDropDown = uidropdown(app.InputsTab);
            app.TestTypeDropDown.Items = {'IGRA', 'TST'};
            app.TestTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TestTypeDropDownValueChanged, true);
            app.TestTypeDropDown.Value = 'IGRA';
            app.RegimenDropDownLabel = uilabel(app.InputsTab);
            app.RegimenDropDownLabel.Text = 'Drop Down';
            app.RegimenDropDown = uidropdown(app.InputsTab);
            app.RegimenDropDown.Items = {'3HP', '4R', '3HR', '6H', '9H'};
            app.RegimenDropDown.ValueChangedFcn = createCallbackFcn(app, @RegimenDropDownValueChanged, true);
            app.RegimenDropDown.Value = '3HP';
            app.ScreeningStrategyDropDownLabel = uilabel(app.InputsTab);
            app.ScreeningStrategyDropDownLabel.Text = 'Screening strategy';
            app.ScreeningStrategyDropDown = uidropdown(app.InputsTab);
            app.ScreeningStrategyDropDown.Items = {'RANDOM', 'LTBI', 'CURE', 'PREVENT'};
            app.ScreeningStrategyDropDown.ValueChangedFcn = createCallbackFcn(app, @ScreeningStrategyDropDownValueChanged, true);
            app.ScreeningStrategyDropDown.Value = 'RANDOM';
            app.ScreenCoverageEditFieldLabel = uilabel(app.InputsTab);
            app.ScreenCoverageEditFieldLabel.Text = 'Screen Coverage';
            app.ScreenCoverageEditField = uieditfield(app.InputsTab, 'numeric');
            app.ScreenCoverageEditField.ValueChangedFcn = createCallbackFcn(app, @ScreenCoverageEditFieldValueChanged, true);
            app.ProportionStartTPTEditFieldLabel = uilabel(app.InputsTab);
            app.ProportionStartTPTEditFieldLabel.Text = 'Proportion Start TPT';
            app.ProportionStartTPTEditField = uieditfield(app.InputsTab, 'numeric');
            app.ProportionStartTPTEditField.ValueChangedFcn = createCallbackFcn(app, @ProportionStartTPTEditFieldValueChanged, true);
            app.ProportionCompleteRegimenEditFieldLabel = uilabel(app.InputsTab);
            app.ProportionCompleteRegimenEditFieldLabel.Text = 'Proportion Complete Regimen';
            app.ProportionCompleteRegimenEditField = uieditfield(app.InputsTab, 'numeric');
            app.ProportionCompleteRegimenEditField.ValueChangedFcn = createCallbackFcn(app, @ProportionCompleteRegimenEditFieldValueChanged, true);
            app.ProportionceaseduetoADREditFieldLabel = uilabel(app.InputsTab);
            app.ProportionceaseduetoADREditFieldLabel.Text = 'Proportion cease due to ADR';
            app.ProportionceaseduetoADREditField = uieditfield(app.InputsTab, 'numeric');
            app.ProportionceaseduetoADREditField.ValueChangedFcn = createCallbackFcn(app, @ProportionceaseduetoADREditFieldValueChanged, true);
            app.RegimeEfficacyforFullCourseEditFieldLabel = uilabel(app.InputsTab);
            app.RegimeEfficacyforFullCourseEditFieldLabel.Text = 'Regime Efficacy for Full Course';
            app.RegimeEfficacyforFullCourseEditField = uieditfield(app.InputsTab, 'numeric');
            app.RegimeEfficacyforFullCourseEditField.ValueChangedFcn = createCallbackFcn(app, @RegimeEfficacyforFullCourseEditFieldValueChanged, true);
            app.AdvancedTab = uitab(app.MainTabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.NrepsEditFieldLabel = uilabel(app.AdvancedTab);
            app.NrepsEditFieldLabel.Text = 'Nreps';
            app.NrepsEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.NrepsEditField.ValueChangedFcn = createCallbackFcn(app, @NrepsEditFieldValueChanged, true);
            app.RunTab = uitab(app.MainTabGroup);
            app.RunTab.Title = 'Run';
            app.RunButton = uibutton(app.RunTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run Scenario';
            app.ValidateButton = uibutton(app.RunTab, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Text = 'Validate Inputs';
            app.ValidationMessagesLabel = uilabel(app.RunTab);
            app.ValidationMessagesLabel.Text = 'Validation Messages';
            app.ValidationMessagesTextArea = uitextarea(app.RunTab);
            app.ResultsTab = uitab(app.MainTabGroup);
            app.ResultsTab.Title = 'Results';
            app.HeadlineResultsTable = uitable(app.ResultsTab);
            app.HeadlineResultsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.HeadlineResultsTable.RowName = {};
            app.TechnicalDetailsLabel = uilabel(app.ResultsTab);
            app.TechnicalDetailsLabel.Text = 'Technical Details';
            app.TechnicalDetailsTextArea = uitextarea(app.ResultsTab);
            app.DownloadsTab = uitab(app.MainTabGroup);
            app.DownloadsTab.Title = 'Downloads';
            app.ExportResultsButton = uibutton(app.DownloadsTab, 'push');
            app.ExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultsButtonPushed, true);
            app.ExportResultsButton.Text = 'Export Results';
            app.DownloadsTable = uitable(app.DownloadsTab);
            app.DownloadsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.DownloadsTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APYV9WebApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end